classdef plotABRs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AutoThresholdButton          matlab.ui.control.Button
        ShowScaleBarCheckBox         matlab.ui.control.CheckBox
        saveButton_Image             matlab.ui.control.Button
        FilterDropDown               matlab.ui.control.DropDown
        AddButterworthFilterLabel_2  matlab.ui.control.Label
        LowFilterEditField           matlab.ui.control.NumericEditField
        LowEditFieldLabel            matlab.ui.control.Label
        HighFilterEditField          matlab.ui.control.NumericEditField
        HighEditFieldLabel           matlab.ui.control.Label
        saveButton_xlsx              matlab.ui.control.Button
        saveButton_mat               matlab.ui.control.Button
        ThreshSummaryButton          matlab.ui.control.Button
        ThresholdLabel               matlab.ui.control.Label
        MouseNameLabel               matlab.ui.control.Label
        MouseListBox                 matlab.ui.control.ListBox
        FrequenciesLabel             matlab.ui.control.Label
        FreqListBox                  matlab.ui.control.ListBox
        InformationTextArea          matlab.ui.control.TextArea
        ProjectFNLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % set up variables that will be in app handle
        fn = []; % variable for storing filename
        list = {}; % cell for listing all the groups
        details = struct; % setup details struct
        thresh = struct; % setup threshold struct
        idx_group = 1; % index of what group is currently selected
        idx_freq = 1; % index of what frequency is currently selected
        scale = 1; % scaling value to increase or decrease waveform size
        norm = 0; % normalize plot yes/no
        sep = 1e-7; % seperation of lines on plot (0.1 uV)
        autothresh = 0; % was the autothreshold run
        ctrl = 0; % see if control key is pressed
    end
    
    methods (Access = public)
        
        function startfunc(app, fn, data, thresh)

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading Files');

            % write the experiment name at top
            app.ProjectFNLabel.Text = fn;
            app.fn = fn;

            app.details = data;

            % setup thresholds structure if not exist
            if ~exist('thresh', 'var')
                for i = 1:length(data)
                    % fill in frequency info
                    app.thresh(i).freq = app.details(i).frequencies;
                    % loop through frequencies
                    for j = 1:length(app.thresh(i).freq)
                        % set default threshold to -inf, may change later if criteria met
                        app.thresh(i).temp(j) = -inf;
                        app.thresh(i).thresh(j) = -inf;
                        app.thresh(i).user(j) = 0;
                    end
                end
            else
                app.thresh = thresh;
            end

            % make list to propogate group listbox
            for i = 1:length(data)
                if ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1, ' : ', app.details(i).ref2];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref2];
                else
                    app.list{i} = app.details(i).ID;
                end
                % catch if there is a double entry and add a number to it
                val = length(find(contains(app.list,app.list{i})));
                if val > 1
                    app.list{i} = [app.list{i}, ' n', num2str(val)];
                end
            end


            % propogate ListBox.
            app.MouseListBox.Items = app.list; 

            % run replot function
            replot(app);

            % close waitbar window
            close(w)
        end
        
    end
    
    methods (Access = private)
        
        function replot(app)

            % catch for bad filter values
            if app.LowFilterEditField.Visible
                low = app.LowFilterEditField.Value;
                if low == 0 || low == inf
                    waitfor(msgbox('Filter Value cannot be 0 or inf'))
                    return
                end
            end
            if app.HighFilterEditField.Visible
                high = app.HighFilterEditField.Value;
                if high == 0 || high == inf
                    waitfor(msgbox('Filter Value cannot be 0 or inf'))
                    return
                end
            end

            % propogate FreqBox.
            app.FreqListBox.Items = app.details(app.idx_group).freqlist;

            % rewrite threshold text
            app.ThresholdLabel.Text = ['Threshold = ', num2str(app.thresh(app.idx_group).thresh(app.idx_freq)), ' dB']; 

            % extract data for plotting
            toPlotAll = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform;
            levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;
            time = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time;
            
            % write detail text on GUI
            txt = rewriteTxt(app.details,app.idx_group,app.idx_freq);
            app.InformationTextArea.Value = txt;

            % clear figure and plot
            cla(app.UIAxes)            
            hold(app.UIAxes, 'on' )
            
            for i = 1:size(toPlotAll,1)

                % extract single waveform and add user defined filter
                toPlot = squeeze(toPlotAll(i,:));
                toPlot = runfilter(app, toPlot); 

                % define line color based on threshold
                if app.thresh(app.idx_group).temp(app.idx_freq) == levels(i)
                    col = 'r';
                else
                    col = 'k';
                end

                % define line style based on threshold
                if app.thresh(app.idx_group).thresh(app.idx_freq) <= levels(i)
                    style = '-';
                else
                    style = '--';
                end

                plot(app.UIAxes, time, (toPlot*app.scale) + (app.sep*(i-1)), 'linewidth', 2, 'color', col, 'LineStyle',style)
            end
    
            % axes details
            yticks(app.UIAxes, 0:app.sep:app.sep * (i-1));
            yticklabels(app.UIAxes, levels)
            ylabel(app.UIAxes, 'Level [dB]')
            xlabel(app.UIAxes, 'Time [ms]')
            ylim(app.UIAxes, [-1 size(toPlotAll,1)]*app.sep)
            set(app.UIAxes,'TickDir','out')

            %draw scalebar
            if app.ShowScaleBarCheckBox.Value
                line(app.UIAxes,[9,9],[0,0.5*app.scale]*app.sep+(app.sep*size(toPlot,1)/2+app.sep*1/4),'linewidth',2, 'color', 'k');
                text(app.UIAxes,9.1,0.2*app.scale*app.sep+(app.sep*size(toPlot,1)/2+app.sep*1/4),'50 uV');
            end


        end
        
        function app = autoABRThresh(app, w)
            % loop through all groups and frequencies and construct threshold struct
            % w is waitbar to be updated

            % calculate total number of trials for waitbar
            tot = 0;
            for i = 1:length(app.details)
                tot = tot + length(app.details(i).frequencies);
            end

            % loop through groups
            count = 1;
            for i = 1:length(app.details)
                                
                % loop through frequencies
                for j = 1:length(app.thresh(i).freq)
                    
                    % extract waveform and levels
                    levels = app.details(i).(['Freq', num2str(j)]).levels;
                    waveform = app.details(i).(['Freq', num2str(j)]).waveform;

                    % add filter if filter was added
                    waveform = runfilter(app, waveform); 

                    % only run autothreshold if there are atleast 3 levels
                    if length(levels) > 3 && ~app.thresh(i).user(j)

                        % run through algorithm to find threshold
                        th = estimate_abr_threshold_SM(waveform', levels');

                        % make sure th isn't empty and update thresh struct
                        if ~isempty(th)
                            app.thresh(i).thresh(j) = th;
                        end
                    end
                    w.Value = count/(tot);
                    count = count + 1;
                end
                
                %set up thresh.temp field which is needed for app functionality
                app.thresh(i).temp = app.thresh(i).thresh;
            end
            
        end
        
        function Y = runfilter(app, Y)
            % function to check and see if user has set a filter and apply filter
            
            % check if filter has been applied
            if ~strcmp(app.FilterDropDown.Value, 'None')

                % add high and low cuttoffs of filter depending on type
                % numbers can only be positive whole numers
                low = nan; high = nan;
                if app.LowFilterEditField.Visible
                    low = app.LowFilterEditField.Value;
                end
                if app.HighFilterEditField.Visible
                    high = app.HighFilterEditField.Value;
                end

                % apply filter
                for i = 1:size(Y,1)
                    Y(i,:) = Butterworthfilter(Y(i,:), 5, [low, high]);
                end
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FreqListBox
        function FreqListBoxValueChanged(app, event)
            
            value = app.FreqListBox.Value;
            app.scale = 1; app.norm = 0; % reset normalization
            app.idx_freq = find(strcmp(app.FreqListBox.Items,value));
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Value changed function: MouseListBox
        function MouseListBoxValueChanged(app, event)
           
            value = app.MouseListBox.Value;
            app.scale = 1; app.norm = 0; % reset normalization
            app.idx_group = find(strcmp(app.MouseListBox.Items,value));
            % catch error if idx_group has multiple enteries
            if length(app.idx_group) > 1
                app.idx_group = app.idx_group(1);
            end
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            
            key = event.Key;

            % signal that the ctrl key is pressed
            if strcmp(key,'control') 
                app.ctrl = 1;
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
           
            key = event.Key;

            % scaling
            if strcmp(key,'add') || strcmp(key,'equal') 
                if app.scale <= 1
                    app.scale = app.scale + 0.1; %scale plot up
                else
                    app.scale = app.scale + 0.5; %scale plot up
                end
            elseif strcmp(key,'subtract') || strcmp(key,'hyphen') 
                if app.scale <= 1
                    if app.scale > 0
                        app.scale = app.scale - 0.1; %scale plot down
                    end
                else
                    app.scale = app.scale - 0.5; %scale plot down
                end
            end

            % move through frequencies or mice if ctrl is held down
            if app.ctrl

                % advance through mice
                if strcmp(key,'downarrow')
                    if app.idx_group < length(app.MouseListBox.Items)
                        app.idx_group = app.idx_group + 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'uparrow')
                    if app.idx_group > 1
                        app.idx_group = app.idx_group - 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

                % advance through frequencies
                if strcmp(key,'rightarrow')
                    if app.idx_freq < length(app.FreqListBox.Items)
                        app.idx_freq = app.idx_freq + 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'leftarrow')
                    if app.idx_freq > 1
                        app.idx_freq = app.idx_freq - 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

            else

                % extract waveform and levels
                levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;

                % move curser up and down levels and set threshold
                if strcmp(key,'uparrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == -inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = min(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) < max(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx+1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == max(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = inf;
                    end
                elseif strcmp(key,'downarrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = max(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) > min(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx-1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == min(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = -inf;
                    end
                elseif strcmp(key,'return')
                    app.thresh(app.idx_group).user(app.idx_freq) = 1;
                    app.thresh(app.idx_group).thresh(app.idx_freq) = app.thresh(app.idx_group).temp(app.idx_freq);
                end
            end

            % normalize plots for easier viewing
            if strcmp(key,'n')
                app.norm = ~app.norm;
                app.scale = 1; % reset normalization
                
                if app.norm
                    app.scale = normalizeScale(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform, app.sep);
                end
            end

            % signal that the ctrl key is pressed
            if strcmp(key,'control') 
                app.ctrl = 0;
            end

            replot(app)

        end

        % Button pushed function: ThreshSummaryButton
        function ThreshSummaryButtonPushed(app, event)
            
            % determine if any thresholds have been manually set
            cont = 0;
            for i = 1:length(app.thresh)
                if any(app.thresh(i).user)
                    cont = 1;
                end
            end

            if cont || app.autothresh
                napp1 = ThresholdSummary;
                startfunc1(napp1, app.list, app.thresh, app.fn, app.details);
                waitfor(napp1)
            else
                % display warning message
                waitfor(warndlg('Run Authothresholds or manually select thresholds'))
            end

        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;

            switch value
                case 'None'
                    app.LowFilterEditField.Visible = 0;
                    app.LowEditFieldLabel.Visible = 0;
                    app.HighFilterEditField.Visible = 0;
                    app.HighEditFieldLabel.Visible = 0;
                case 'LowPass'
                    app.LowFilterEditField.Visible = 0;
                    app.LowEditFieldLabel.Visible = 0;
                    app.HighFilterEditField.Visible = 1;
                    app.HighEditFieldLabel.Visible = 1;
                case 'HighPass'
                    app.LowFilterEditField.Visible = 1;
                    app.LowEditFieldLabel.Visible = 1;
                    app.HighFilterEditField.Visible = 0;
                    app.HighEditFieldLabel.Visible = 0;
                case 'BandPass'
                    app.LowFilterEditField.Visible = 1;
                    app.LowEditFieldLabel.Visible = 1;
                    app.HighFilterEditField.Visible = 1;
                    app.HighEditFieldLabel.Visible = 1;
            end

            replot(app);
            
        end

        % Value changed function: HighFilterEditField
        function HighFilterEditFieldValueChanged(app, event)

            % numbers can only be positive whole numers
            value = app.HighFilterEditField.Value;
            app.HighFilterEditField.Value = abs(round(value));

            replot(app);

        end

        % Value changed function: LowFilterEditField
        function LowFilterEditFieldValueChanged(app, event)

            % numbers can only be positive whole numers
            value = app.LowFilterEditField.Value;
            app.LowFilterEditField.Value = abs(round(value));

            replot(app);

        end

        % Button pushed function: saveButton_xlsx
        function saveButton_xlsxPushed(app, event)

            % add high and low cuttoffs of filter depending on type, nan otherwise
            % numbers can only be positive whole numers
            low = nan; high = nan;
            if app.LowFilterEditField.Visible
                low = app.LowFilterEditField.Value;
            end
            if app.HighFilterEditField.Visible
                high = app.HighFilterEditField.Value;
            end

            saveExcel(app.fn, app.list, app.details, app.thresh, [low, high])
            
        end

        % Button pushed function: saveButton_mat
        function saveButton_matPushed(app, event)
            
            % select folder and file name to save to
            [file,path] = uiputfile([app.fn, '.mat']);

            % catch to make sure the user choose a file
            if sum(file) ~= 0
                
                saveName = fullfile(path, file);

                details = app.details;
                thresh = app.thresh;

                save(saveName, 'details', 'thresh', '-v7.3')
            end
            
        end

        % Button pushed function: saveButton_Image
        function saveButton_ImagePushed(app, event)
            
            % define types of files the figure can be saved as
            type = {'*.png'; '*.jpg'; '*.tif'; '*.svg'; '*.pdf'; '*.fig'};

            % select folder and file name to save to
            [file,path,idx] = uiputfile(type, 'File Selection', app.fn);

            % catch to make sure the user choose a file
            if sum(file) == 0
                return
            end

            % make saveName
            saveName = fullfile(path, file);

            saveImage(saveName, app.UIAxes, idx)

        end

        % Value changed function: ShowScaleBarCheckBox
        function ShowScaleBarCheckBoxValueChanged(app, event)
            
            replot(app);
            
        end

        % Button pushed function: AutoThresholdButton
        function AutoThresholdButtonPushed(app, event)
            
            % update the autothresh boolean
            app.autothresh = 1;

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Calculating Thresholds');

            % setup thresholds structure
            app = autoABRThresh(app, w);

            % display warning message
            waitfor(warndlg('Auto Thresholding complete. Go through and double check thresholds are correct'))

            replot(app);

            % close waitbar window
            close(w)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ProjectFNLabel = uilabel(app.UIFigure);
            app.ProjectFNLabel.Text = 'Project FN';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {'information'};
            app.FreqListBox = uilistbox(app.UIFigure);
            app.FreqListBox.ValueChangedFcn = createCallbackFcn(app, @FreqListBoxValueChanged, true);
            app.FrequenciesLabel = uilabel(app.UIFigure);
            app.FrequenciesLabel.Text = 'Frequencies';
            app.MouseListBox = uilistbox(app.UIFigure);
            app.MouseListBox.ValueChangedFcn = createCallbackFcn(app, @MouseListBoxValueChanged, true);
            app.MouseNameLabel = uilabel(app.UIFigure);
            app.MouseNameLabel.Text = 'Mouse Name';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold = ';
            app.ThreshSummaryButton = uibutton(app.UIFigure, 'push');
            app.ThreshSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ThreshSummaryButtonPushed, true);
            app.ThreshSummaryButton.Text = 'Thresh Summary';
            app.saveButton_mat = uibutton(app.UIFigure, 'push');
            app.saveButton_mat.ButtonPushedFcn = createCallbackFcn(app, @saveButton_matPushed, true);
            app.saveButton_mat.Text = 'Save to Mat';
            app.saveButton_xlsx = uibutton(app.UIFigure, 'push');
            app.saveButton_xlsx.ButtonPushedFcn = createCallbackFcn(app, @saveButton_xlsxPushed, true);
            app.saveButton_xlsx.Text = 'Save to Excel';
            app.HighEditFieldLabel = uilabel(app.UIFigure);
            app.HighEditFieldLabel.Visible = 'off';
            app.HighEditFieldLabel.Text = 'High Cut';
            app.HighFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighFilterEditField.ValueChangedFcn = createCallbackFcn(app, @HighFilterEditFieldValueChanged, true);
            app.HighFilterEditField.Visible = 'off';
            app.HighFilterEditField.Value = 3000;
            app.LowEditFieldLabel = uilabel(app.UIFigure);
            app.LowEditFieldLabel.Visible = 'off';
            app.LowEditFieldLabel.Text = 'Low Cut';
            app.LowFilterEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowFilterEditField.ValueChangedFcn = createCallbackFcn(app, @LowFilterEditFieldValueChanged, true);
            app.LowFilterEditField.Visible = 'off';
            app.LowFilterEditField.Value = 30;
            app.AddButterworthFilterLabel_2 = uilabel(app.UIFigure);
            app.AddButterworthFilterLabel_2.Text = 'Add Butterworth Filter';
            app.FilterDropDown = uidropdown(app.UIFigure);
            app.FilterDropDown.Items = {'None', 'HighPass', 'LowPass', 'BandPass'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'None';
            app.saveButton_Image = uibutton(app.UIFigure, 'push');
            app.saveButton_Image.ButtonPushedFcn = createCallbackFcn(app, @saveButton_ImagePushed, true);
            app.saveButton_Image.Text = 'Save Image';
            app.ShowScaleBarCheckBox = uicheckbox(app.UIFigure);
            app.ShowScaleBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowScaleBarCheckBoxValueChanged, true);
            app.ShowScaleBarCheckBox.Text = 'Show Scale Bar';
            app.ShowScaleBarCheckBox.Value = true;
            app.AutoThresholdButton = uibutton(app.UIFigure, 'push');
            app.AutoThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AutoThresholdButtonPushed, true);
            app.AutoThresholdButton.Text = 'Auto Threshold';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotABRs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end