classdef plotDPOAEs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        return_button                 matlab.ui.control.Button
        AutoThresholdButton           matlab.ui.control.Button
        saveButton_Image              matlab.ui.control.Button
        ThreshSummaryButton           matlab.ui.control.Button
        MicsenseEditFieldLabel_units  matlab.ui.control.Label
        MicSenseEditField             matlab.ui.control.NumericEditField
        MicrophoneSensitivityEditFieldLabel  matlab.ui.control.Label
        saveButton_xlsx               matlab.ui.control.Button
        saveButton_mat                matlab.ui.control.Button
        ThresholdLabel                matlab.ui.control.Label
        MouseNameLabel                matlab.ui.control.Label
        MouseListBox                  matlab.ui.control.ListBox
        FrequenciesLabel              matlab.ui.control.Label
        FreqListBox                   matlab.ui.control.ListBox
        InformationTextArea           matlab.ui.control.TextArea
        ProjectFNLabel                matlab.ui.control.Label
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % set up variables that will be in app handle
        fn = []; % variable for storing filename
        path = []; %variable for storing original file path
        list = {}; % cell for listing all the groups
        details = struct; % setup details struct
        thresh = struct; % setup threshold struct
        idx_group = 1; % index of what group is currently selected
        idx_freq = 1; % index of what frequency is currently selected
        scale = 1; % scaling value to increase or decrease waveform size
        norm = 0; % normalize plot yes/no
        sep = 150; % seperation of lines on plot
        autothresh = 0; % was the autothreshold run
        ctrl = 0; % see if control key is pressed
    end
    
    methods (Access = public)
        
        function startfunc(app, path, fn, data, thresh)

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Loading data');

            % write the experiment name at top
            app.ProjectFNLabel.Text = fn;
            app.fn = fn;
            app.path = path;

            app.details = data;

            % setup thresholds structure if not exist
            if ~exist('thresh', 'var')
                for i = 1:length(data)
                    % fill in frequency info
                    app.thresh(i).freq = app.details(i).frequencies;
                    % loop through frequencies
                    for j = 1:length(app.thresh(i).freq)
                        % set default threshold to -inf, may change later if criteria met
                        app.thresh(i).temp(j) = -inf;
                        app.thresh(i).thresh(j) = -inf;
                        app.thresh(i).user(j) = 0;
                    end
                end
            else
                app.thresh = thresh;
            end

            % make list to propogate group listbox
            for i = 1:length(data)
                if ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1, ' : ', app.details(i).ref2];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref1)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref1];
                elseif ~isempty(app.details(i).ID) && ~isempty(app.details(i).ref2)
                    app.list{i} = [app.details(i).ID, ' : ', app.details(i).ref2];
                else
                   app.list{i} = app.details(i).ID;
                end
            end

            % propogate ListBox.
            app.MouseListBox.Items = app.list; 

            % run replot function
            replot(app);

            % close waitbar window
            close(w)

        end
        
    end
    
    methods (Access = private)
        
        function replot(app)
            
            % propogate FreqBox.
            app.FreqListBox.Items = app.details(app.idx_group).freqlist;

            % rewrite threshold text
            app.ThresholdLabel.Text = ['Threshold = ', num2str(app.thresh(app.idx_group).thresh(app.idx_freq)), ' dB']; 

            % extract data for plotting
            toPlotAll = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform;
            levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;
            spectra = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time;
            cur = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).cur;
            
            % write detail text on GUI
            txt = rewriteTxt(app.details,app.idx_group,app.idx_freq);
            app.InformationTextArea.Value = txt;

            % clear figure and plot
            cla(app.UIAxes)            
            hold(app.UIAxes, 'on' )

            for i = 1:size(toPlotAll,1)

                % extract single waveform and add user defined filter
                toPlot = squeeze(toPlotAll(i,:));

                % convert to DB SPL
                toPlot = convert2DB(app, toPlot);

                % define line color based on threshold
                if app.thresh(app.idx_group).temp(app.idx_freq) == levels(i)
                    col = 'r';
                else
                    if app.thresh(app.idx_group).thresh(app.idx_freq) <= levels(i)
                        col = [0, 0, 0];
                    else
                        col = [0.75 0.75 0.75];
                    end
                end


                plot(app.UIAxes, spectra/1000, (toPlot*app.scale) + (app.sep*(i-1)), 'linewidth', 2, 'color', col, 'LineStyle','-')
                
                % plot F1 F2 and DP
                plot(app.UIAxes, spectra(cur.F1_loc)/1000, (toPlot(cur.F1_loc)*app.scale) + (app.sep * (i-1)), 'color', 'r', 'marker', 's', 'MarkerFaceColor', 'r')
                plot(app.UIAxes, spectra(cur.F2_loc)/1000, (toPlot(cur.F2_loc)*app.scale) + (app.sep * (i-1)), 'color', 'r', 'marker', 's', 'MarkerFaceColor', 'r')
                plot(app.UIAxes, spectra(cur.DP_loc)/1000, (toPlot(cur.DP_loc)*app.scale) + (app.sep * (i-1)), 'color', 'r', 'marker', '^', 'MarkerFaceColor', 'r')

                % gather info for growth function
                growFun.F1(i) = toPlot(cur.F1_loc);
                growFun.F2(i) = toPlot(cur.F2_loc);
                growFun.DP(i) = toPlot(cur.DP_loc);
                noise = [toPlot(cur.DP_loc-10:cur.DP_loc-1), toPlot(cur.DP_loc+2:cur.DP_loc+8)];
                growFun.noise(i) = mean(noise + 3*std(noise));
            end
    
            % axes details
            yticks(app.UIAxes, 0:app.sep:app.sep * (i-1));
            yticklabels(app.UIAxes, levels)
            ylabel(app.UIAxes, 'Level [dB]')
            xlabel(app.UIAxes, 'Frequency [kHz]')
            ylim(app.UIAxes, [-1 size(toPlotAll,1)]*app.sep)
            xlim(app.UIAxes, [0 50])
            set(app.UIAxes,'TickDir','out')
            
            % to go replotzoom function to plot zoomin of spectra
            replotZoom(app)
            
            % go to replotgrowfun to replot growth function of F1, F2, DP, and noise power
            replotGrowFun(app, levels, growFun)

        end

        function replotZoom(app)
            
            % extract data for plotting
            toPlotAll = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform;
            levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;
            spectra = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).time;
            cur = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).cur;

            % clear figure and plot
            cla(app.UIAxes_3)            
            hold(app.UIAxes_3, 'on' )

            % find index of selected level
            if app.thresh(app.idx_group).temp(app.idx_freq) == -inf
                idx = 1;
            elseif app.thresh(app.idx_group).temp(app.idx_freq) == inf
                idx = length(levels);
            else
                idx = find(levels == app.thresh(app.idx_group).temp(app.idx_freq));
            end
    
            % extract data, convert to dB SPL, and plot
            Y = squeeze(toPlotAll(idx,:));      
            Y = convert2DB(app, Y);
            plot(app.UIAxes_3, spectra/1000, (Y), 'linewidth', 0.5, 'color', 'k')
                
            % plot F1 F2 and DP
            plot(app.UIAxes_3, spectra(cur.F1_loc)/1000, Y(cur.F1_loc), 'color', 'r', 'marker', 's', 'MarkerFaceColor', 'r')
            plot(app.UIAxes_3, spectra(cur.F2_loc)/1000, Y(cur.F2_loc), 'color', 'r', 'marker', 's', 'MarkerFaceColor', 'r')
            plot(app.UIAxes_3, spectra(cur.DP_loc)/1000, Y(cur.DP_loc), 'color', 'r', 'marker', '^', 'MarkerFaceColor', 'r')
    
            % axes details
            ylabel(app.UIAxes_3, 'Power [dB SPL]')
            xlabel(app.UIAxes_3, 'Frequency [kHz]')
            if cur.DP_loc > 75 
                xlim(app.UIAxes_3, [spectra(cur.DP_loc-75)/1000, spectra(cur.DP_loc+75)/1000])
            else
                xlim(app.UIAxes_3, [spectra(1)/1000, spectra(cur.DP_loc+75)/1000])
            end
            set(app.UIAxes_3,'TickDir','out')
            ylim(app.UIAxes_3, [-50 100])

        end

        function replotGrowFun(app, levels, growFun)
            
            % clear figure and plot and add grid
            cla(app.UIAxes_2)            
            hold(app.UIAxes_2, 'on' )
            grid(app.UIAxes_2, 'on')

            % plot growth functions
            plot(app.UIAxes_2, levels, growFun.F1, 'marker', 'o', 'linestyle', 'none', 'markeredgecolor', 'k', 'MarkerFaceColor', 'k')
            plot(app.UIAxes_2, levels, growFun.F2, 'marker', 'o', 'linestyle', 'none', 'markeredgecolor', 'k')
            plot(app.UIAxes_2, levels, growFun.DP, 'marker', 'o', 'linestyle', 'none', 'markeredgecolor', 'b', 'MarkerFaceColor', 'b')
            plot(app.UIAxes_2, levels, growFun.noise, 'marker', 'x', 'linestyle', 'none', 'markeredgecolor', 'k')

            % plot threshold line if there is one
            if ~isnan(app.thresh(app.idx_group).temp(app.idx_freq))
                line(app.UIAxes_2, [app.thresh(app.idx_group).temp(app.idx_freq), app.thresh(app.idx_group).temp(app.idx_freq)], [-100 1000], 'linestyle', '--', 'color', 'k', 'linewidth', 1)
            end

            % axes details
            xticks(app.UIAxes_2, min(levels) - 10:10:max(levels)+10);
            xlabel(app.UIAxes_2, 'Level [dB]')
            ylabel(app.UIAxes_2, 'dB SPL')
            legend(app.UIAxes_2, {'F1', 'F2', '2F1-F2', 'noise', 'threshold'}, 'location', 'northoutside')
            xlim(app.UIAxes_2, [min(levels) - 10, max(levels)+10])
            ylim(app.UIAxes_2, [-20 100])
            set(app.UIAxes_2,'TickDir','out')

        end
        
        function app = autoDPOAEThresh(app)
            % loop through all groups and frequencies and construct threshold struct
            
            % loop through groups
            for i = 1:length(app.details)
                
                % loop through frequencies
                for j = 1:length(app.thresh(i).freq)
                    
                    %extract waveform and DP_loc and levels
                    wav = app.details(i).(['Freq', num2str(j)]).waveform;
                    cur = app.details(i).(['Freq', num2str(j)]).cur;
                    levels = app.details(i).(['Freq', num2str(j)]).levels;

                    % loop through levels
                    noise = [];
                    for k = 1:size(wav,1)

                        Y = squeeze(wav(k,:));
        
                        % find amplitude of DP
                        DP(k) = Y(cur.DP_loc);

                        % extract area around DP location for noise calculation
                        noi = [Y(cur.DP_loc-10:cur.DP_loc-1), Y(cur.DP_loc+2:cur.DP_loc+8)];
                        
                        noise = [noise, noi];
                    end
                    
                    % set threshold
                    th = mean(noise)+3*std(noise);

                    % find DP that are greater than th
                    app.thresh(i).thresh(j) = -inf;
                    if any(DP > th)
                        %catch errors if just a single DP was above th but wasn't real
                        if sum(DP>th) == 1
                            if find(DP > th) == length(levels)
                                tapp.hresh(i).thresh(j) = levels(end);
                            end
                        elseif diff(find(DP > th,2)) == 1
                            app.thresh(i).thresh(j) = levels(find(DP > th,1));
                        elseif any(diff(find(DP > th)) == 1)
                            greater = find(DP > th);
                            idx = find(diff(greater) == 1,1);
                            app.thresh(i).thresh(j) = levels(greater(idx));
                        else
                            app.thresh(i).thresh(j) = inf;
                        end
                    end
                end

                %set up thresh.temp field which is needed for app functionality
                app.thresh(i).temp = app.thresh(i).thresh;
            end
        end

        function wav = convert2DB(app, wav)

            % equation for converting from the microphone voltage to dB SPL
            equa = @(x) 20*log10(10.^(x/20) / (app.MicSenseEditField.Value/1000));

            % run waveform through equation
            wav = equa(wav);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FreqListBox
        function FreqListBoxValueChanged(app, event)
            
            value = app.FreqListBox.Value;
            app.scale = 1; app.norm = 0; % reset normalization
            app.idx_freq = find(strcmp(app.FreqListBox.Items,value));
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Value changed function: MouseListBox
        function MouseListBoxValueChanged(app, event)
            
            value = app.MouseListBox.Value;
            app.scale = 1; app.norm = 0; % reset normalization
            app.idx_group = find(strcmp(app.MouseListBox.Items,value));
            app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
            replot(app)

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
             
            key = event.Key;

            % signal that the ctrl key is pressed
            if strcmp(key,'control') 
                app.ctrl = 1;
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
           
            key = event.Key;
            
            % scaling
            if strcmp(key,'add') || strcmp(key,'equal') 
                if app.scale <= 1
                    app.scale = app.scale + 0.1; %scale plot up
                else
                    app.scale = app.scale + 0.5; %scale plot up
                end
            elseif strcmp(key,'subtract') || strcmp(key,'hyphen') 
                if app.scale <= 1
                    if app.scale > 0
                        app.scale = app.scale - 0.1; %scale plot down
                    end
                else
                    app.scale = app.scale - 0.5; %scale plot down
                end
            end

            % move through frequencies or mice if ctrl is held down
            if app.ctrl

                % advance through mice
                if strcmp(key,'downarrow')
                    if app.idx_group < length(app.MouseListBox.Items)
                        app.idx_group = app.idx_group + 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'uparrow')
                    if app.idx_group > 1
                        app.idx_group = app.idx_group - 1;
                        app.MouseListBox.Value = app.MouseListBox.Items(app.idx_group);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

                % advance through frequencies
                if strcmp(key,'rightarrow')
                    if app.idx_freq < length(app.FreqListBox.Items)
                        app.idx_freq = app.idx_freq + 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                elseif strcmp(key,'leftarrow')
                    if app.idx_freq > 1
                        app.idx_freq = app.idx_freq - 1;
                        app.FreqListBox.Value = app.FreqListBox.Items(app.idx_freq);
                        app.thresh(app.idx_group).temp(app.idx_freq) = app.thresh(app.idx_group).thresh(app.idx_freq);
                        app.scale = 1; app.norm = 0; % reset normalization
                    end
                end

            else

                % extract waveform and levels
                levels = app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).levels;

                % move curser up and down levels and set threshold
                if strcmp(key,'uparrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == -inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = min(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) < max(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx+1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == max(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = inf;
                    end
                elseif strcmp(key,'downarrow')
                    if app.thresh(app.idx_group).temp(app.idx_freq) == inf
                        app.thresh(app.idx_group).temp(app.idx_freq) = max(levels);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) > min(levels)
                        idx = find(app.thresh(app.idx_group).temp(app.idx_freq)  == levels);
                        app.thresh(app.idx_group).temp(app.idx_freq) = levels(idx-1);
                    elseif app.thresh(app.idx_group).temp(app.idx_freq) == min(levels)
                        app.thresh(app.idx_group).temp(app.idx_freq) = -inf;
                    end
                elseif strcmp(key,'return')
                    app.thresh(app.idx_group).user(app.idx_freq) = 1;
                    app.thresh(app.idx_group).thresh(app.idx_freq) = app.thresh(app.idx_group).temp(app.idx_freq);
                end
            end

            % normalize plots for easier viewing
            if strcmp(key,'n')
                app.norm = ~app.norm;
                app.scale = 1; % reset normalization
                
                if app.norm
                    app.scale = normalizeScale(app.details(app.idx_group).(['Freq', num2str(app.idx_freq)]).waveform, app.sep);
                end
            end

            % signal that the ctrl key is pressed
            if strcmp(key,'control') 
                app.ctrl = 0;
            end

            replot(app)
        end

        % Button pushed function: ThreshSummaryButton
        function ThreshSummaryButtonPushed(app, event)
            
            napp1 = ThresholdSummary;
            startfunc1(napp1, app.list, app.thresh, app.path, app.fn, app.details);
            waitfor(napp1)

        end

        % Value changed function: InformationTextArea
        function InformationTextAreaValueChanged(app, event)

            replot(app)

        end

        % Button pushed function: saveButton_xlsx
        function saveButton_xlsxPushed(app, event)
            
            saveExcel(app.path, app.fn, app.list, app.details, app.thresh)
            
        end

        % Button pushed function: saveButton_mat
        function saveButton_matPushed(app, event)
            
            % select folder and file name to save to
            [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

            % catch to make sure the user choose a file
            if sum(file) ~= 0
                saveName = fullfile(path, file);

                details = app.details;
                thresh = app.thresh;

                save(saveName, 'details', 'thresh', '-v7.3')
            end

        end

        % Button pushed function: saveButton_Image
        function saveButton_ImagePushed(app, event)

            % define types of files the figure can be saved as
            type = {'*.png'; '*.jpg'; '*.tif'; '*.svg'; '*.pdf'; '*.fig'};

            % select folder and file name to save to
            [file,path,idx] = uiputfile(type, 'File Selection', fullfile(app.path, app.fn));

            % catch to make sure the user choose a file
            if sum(file) == 0
                return
            end

            % make saveName
            saveName = fullfile(path, file);
            saveName1 = [saveName(1:end-4), '_1', saveName(end-3:end)];
            saveName2 = [saveName(1:end-4), '_2', saveName(end-3:end)];
            saveName3 = [saveName(1:end-4), '_3', saveName(end-3:end)];

            saveImage(saveName1, app.UIAxes, type{idx})
            saveImage(saveName2, app.UIAxes_2, type{idx})
            saveImage(saveName3, app.UIAxes_3, type{idx})

        end

        % Button pushed function: AutoThresholdButton
        function AutoThresholdButtonPushed(app, event)
            
            % update the autothresh boolean
            app.autothresh = 1;

            % open waitbar window
            w = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Calculating Thresholds');

            % setup thresholds structure
            app = autoDPOAEThresh(app);

            % display warning message
            waitfor(warndlg('Auto Thresholding complete. Go through and double check thresholds are correct'))

            replot(app);

            % close waitbar window
            close(w)

        end

        % Value changed function: MicSenseEditField
        function MicSenseEditFieldValueChanged(app, event)

            replot(app);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            % open dialog box to ask if files want to be saved
            answer = questdlg('Would you to save?', 'answer', 'Yes','No', 'Yes');
            
            if ~isempty(answer)
                if strcmp(answer, 'Yes')
                    % select folder and file name to save to
                    [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

                    % catch to make sure the user choose a file
                    if sum(file) ~= 0

                        saveName = fullfile(path, file);

                        details = app.details;
                        thresh = app.thresh;

                        save(saveName, 'details', 'thresh', '-v7.3')
                    else
                        return
                    end
                end
                delete(app)
            end
            
        end

        % Button pushed function: return_button
        function return_buttonButtonPushed(app, event)
            
            % open dialog box to ask if files want to be saved
            answer = questdlg('Would you to save?', 'answer', 'Yes','No', 'Yes');
            
            if ~isempty(answer)
                if strcmp(answer, 'Yes')
                    % select folder and file name to save to
                    [file,path] = uiputfile(fullfile(app.path, [app.fn, '.mat']));

                    % catch to make sure the user choose a file
                    if sum(file) ~= 0

                        saveName = fullfile(path, file);

                        details = app.details;
                        thresh = app.thresh;

                        save(saveName, 'details', 'thresh', '-v7.3')
                    else
                        return
                    end
                end
                delete(app)

                % reopen AcousticPlotterStartGUI
                app = AcousticPlotterStartGUI;
                % call app
                startfuncmaster(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.ProjectFNLabel = uilabel(app.UIFigure);
            app.ProjectFNLabel.Text = 'Project FN';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.InformationTextArea.ValueChangedFcn = createCallbackFcn(app, @InformationTextAreaValueChanged, true);
            app.InformationTextArea.Editable = 'off';
            app.InformationTextArea.Value = {'information'};
            app.FreqListBox = uilistbox(app.UIFigure);
            app.FreqListBox.ValueChangedFcn = createCallbackFcn(app, @FreqListBoxValueChanged, true);
            app.FrequenciesLabel = uilabel(app.UIFigure);
            app.FrequenciesLabel.Text = 'Frequencies';
            app.MouseListBox = uilistbox(app.UIFigure);
            app.MouseListBox.ValueChangedFcn = createCallbackFcn(app, @MouseListBoxValueChanged, true);
            app.MouseNameLabel = uilabel(app.UIFigure);
            app.MouseNameLabel.Text = 'Mouse Name';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold = ';
            app.saveButton_mat = uibutton(app.UIFigure, 'push');
            app.saveButton_mat.ButtonPushedFcn = createCallbackFcn(app, @saveButton_matPushed, true);
            app.saveButton_mat.Text = 'Save to Mat';
            app.saveButton_xlsx = uibutton(app.UIFigure, 'push');
            app.saveButton_xlsx.ButtonPushedFcn = createCallbackFcn(app, @saveButton_xlsxPushed, true);
            app.saveButton_xlsx.Text = 'Save to Excel';
            app.MicrophoneSensitivityEditFieldLabel = uilabel(app.UIFigure);
            app.MicrophoneSensitivityEditFieldLabel.Text = 'Microphone Sensitivity';
            app.MicSenseEditField = uieditfield(app.UIFigure, 'numeric');
            app.MicSenseEditField.ValueChangedFcn = createCallbackFcn(app, @MicSenseEditFieldValueChanged, true);
            app.MicSenseEditField.Value = 50;
            app.MicsenseEditFieldLabel_units = uilabel(app.UIFigure);
            app.MicsenseEditFieldLabel_units.Text = 'mV/pascal';
            app.ThreshSummaryButton = uibutton(app.UIFigure, 'push');
            app.ThreshSummaryButton.ButtonPushedFcn = createCallbackFcn(app, @ThreshSummaryButtonPushed, true);
            app.ThreshSummaryButton.Text = 'Thresh Summary';
            app.saveButton_Image = uibutton(app.UIFigure, 'push');
            app.saveButton_Image.ButtonPushedFcn = createCallbackFcn(app, @saveButton_ImagePushed, true);
            app.saveButton_Image.Text = 'Save Images';
            app.AutoThresholdButton = uibutton(app.UIFigure, 'push');
            app.AutoThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @AutoThresholdButtonPushed, true);
            app.AutoThresholdButton.Text = 'Auto Threshold';
            app.return_button = uibutton(app.UIFigure, 'push');
            app.return_button.ButtonPushedFcn = createCallbackFcn(app, @return_buttonButtonPushed, true);
            app.return_button.Text = '<<< Return ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotDPOAEs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end